classdef VisFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SamplingPanel        matlab.ui.container.Panel
        POIQOIScatter        matlab.ui.control.UIAxes
        FitModelButton       matlab.ui.control.Button
        ModelDropDownLabel   matlab.ui.control.Label
        ModelSelectDropDown  matlab.ui.control.DropDown
        DummyResidualsLabel  matlab.ui.control.Label
        DummyfxLabel         matlab.ui.control.Label
        ResidualsLabel       matlab.ui.control.Label
        fxLabel              matlab.ui.control.Label
        POIsPanel            matlab.ui.container.Panel
        POIHist              matlab.ui.control.UIAxes
        QOIsPanel            matlab.ui.container.Panel
        QOIHist              matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        RunModelButton       matlab.ui.control.Button
        POI1DropDownLabel    matlab.ui.control.Label
        POI1DropDown         matlab.ui.control.DropDown
        QOIDropDownLabel     matlab.ui.control.Label
        QOIDropDown          matlab.ui.control.DropDown
        POI2DropDownLabel    matlab.ui.control.Label
        POI2DropDown         matlab.ui.control.DropDown
    end


    methods (Access = private)
        function MakeModel(app)
            %In reality this will run some large code like GSA
             Data.POI=randn(1000,2);
             Data.QOI=randn(1000,2);
             setappdata(app.UIFigure,'Data',Data);
        end
        
        
        function AssignModel(app,Name)
            %Model Class Definition
               %.Name: String of Model Name
               %.Type: 'Linear' v 'NonLinear'
               %.Coeff: vector of coeffecients, passed as zeros initially
               %.EvalFunc: Function that takes x (single or column vector) and 
               %            vector of coeffecients and outputs y with dim ==dim(x)
               %.XFunc: Linear Models only. Function that takes x (single or 
               %        column vector) and outputs X with size [length(x) length(Coeff)]
               %        where Xij is xi evaluated at the jth basis function of the Model
               %.StringFormat: formatspec for sprintf call to display basis function with Coeff.
               %               written so string will be passed through latex interpreter
               %.RTR: Residual sum of squares for the model fit, NaN before assignment in FitModel
            
            switch Name
                case '1st Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x);
                    Model.XFunc=@(x)[ones(size(x)) x];
                    Model.StringFormat="$f(x)=%.2f%+.2fx$";
                    Model.RTR=NaN(1);
                case '2nd Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x+Coeff(3).*x.^2);
                    Model.XFunc=@(x)[ones(size(x)) x x.^2];
                    Model.StringFormat="$f(x)=%.2f%+.2fx%+.2fx^2$";
                    Model.RTR=NaN(1);
                case '3rd Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x+Coeff(3).*x.^2+Coeff(4).*x.^3);
                    Model.XFunc=@(x)[ones(size(x)) x x.^2 x.^3];
                    Model.StringFormat="$f(x)=%.2f%+.2fx%+.2fx^2%+.2fx^3$";
                    Model.RTR=NaN(1);
                case 'Logistic'
                    Model.Name=Name;
                    Model.Type='NonLinear';
                    Model.Coeff=[0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)./(1+exp(-Coeff(2).*(x-Coeff(3)))));
                    Model.StringFormat="$f(x)=\\frac{%.2f}{1+e^{%.2f(x-%.2f)}}$";
                    Model.RTR=NaN(1);
                case '(Select)'
                    %Put something here to clear the model fitting
                otherwise
                    error('Model Name not recognized')
            end
            setappdata(app.UIFigure,'Model',Model)
            FitModel(app);
        end
    
        function FitModel(app)
            %ModelClass
               %.Name: String of Model Name
               %.Type: 'Linear' v 'NonLinear'
               %.Coeff: vector of coeffecients, passed as zeros initially
               %.EvalFunc: Function that takes x (single or column vector) and 
               %            vector of coeffecients and outputs y with dim ==dim(x)
               %.XFunc: Linear Models only. Function that takes x (single or 
               %        column vector) and outputs X with size [length(x) length(Coeff)]
               %        where Xij is xi evaluated at the jth basis function of the Model
               %.StringFormat: formatspec for sprintf call to display basis function with Coeff.
               %               written so string will be passed through latex interpreter
               %.RTR: Residual sum of squares for the model fit, NaN before assignment in FitModel
            %Load Data
            QOI=getappdata(app.UIFigure,'QOISelection');
            POI=getappdata(app.UIFigure,'POISelection');
            
            %Get basis function
            Model=getappdata(app.UIFigure,'Model');
            
            %Get Coeffecients-Fitting Linear Model
            if strcmpi(Model.Type,'Linear')
                X=Model.XFunc(POI);
                Coeff=(X'*QOI)\(X'*X);
            elseif strcmpi(Model.Type,'NonLinear')
                x0=zeros(1,length(Model.Coeff));
                RTRFunc=@(x)(sum((QOI-Model.EvalFunc(x,POI)).^2));
                Coeff=lsqnonlin(RTRFunc,x0);
            else
                error('Unrecognized basis.Type')
            end
            
            %Get sum residuals squared
            R=Model.EvalFunc(Coeff,POI);
            RTR=R'*R;
            
            %Pass Results
            Model.RTR=RTR;
            Model.Coeff=Coeff;
            setappdata(app.UIFigure,'Model',Model)
            
            %Plot Results
            DisplayFit(app)
        end
    
        function DisplayFit(app)
            Model=getappdata(app.UIFigure,'Model');
            %Plot model on scatter
                POIs=getappdata(app.UIFigure,'POISelection');
                POIRange=linspace(min(POIs),max(POIs),200);
                hold(app.POIQOIScatter)
                plot(app.POIQOIScatter,POIRange,Model.EvalFunc(POIRange,Model.Coeff))
                hold(app.POIQOIScatter)
                %label
                flegendString=sprintf(Model.StringFormat,Model.Coeff);
                L=legend(app.POIQOIScatter,"Data",flegendString);
                set(L,'Interpreter','LaTex')
            %Edit ResidualLabel
            ResidualLabelString=sprintf("%.3e",Model.RTR);
            setappdata(app.ResidualsLabel,'Text',ResidualLabelString)
            
            %Edit ResidualLabel
        end


        function MakePOIHist(app)
            POISelection=getappdata(app.UIFigure,'POISelection');
            POIName=getappdata(app.UIFigure,'POIName');
            histogram(app.POIHist,POISelection)
            xlabel(app.POIHist,POIName)
        end
    
        
        function MakeQOIHist(app)
            QOISelection=getappdata(app.UIFigure,'QOISelection');
            QOIName=getappdata(app.UIFigure,'QOIName');
            histogram(app.QOIHist,QOISelection)
            xlabel(app.QOIHist,QOIName)
        end
        
        function MakePOIQOIScatter(app)
            QOISelection=getappdata(app.UIFigure,'QOISelection');
            QOIName=getappdata(app.UIFigure,'QOIName');
            POISelection=getappdata(app.UIFigure,'POISelection');
            POIName=getappdata(app.UIFigure,'POIName');
            scatter(app.POIQOIScatter,POISelection,QOISelection)
            xlabel(app.POIQOIScatter,POIName);
            ylabel(app.POIQOIScatter,QOIName);
        end
       
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MakeModel(app)
            Data=getappdata(app.UIFigure,'Data');
            
            setappdata(app.UIFigure,'POIName','\alpha^{SV}_{SH}');
            setappdata(app.UIFigure,'POISelection',Data.POI(:,1));
            
            setappdata(app.UIFigure,'QOIName','% Infected');
            setappdata(app.UIFigure,'QOISelection',Data.QOI(:,1));
            MakePOIHist(app);
            MakeQOIHist(app);
            MakePOIQOIScatter(app);
        end

        % Button pushed function: RunModelButton
        function ReMakeModel(app, event)
        %In reality this will run some large code like GSA
             MakeModel(app);
        end

        % Value changed function: POI1DropDown
        function POISelect1(app, event)
             POIName=app.POI1DropDown.Value;
             setappdata(app.UIFigure,'POIName',POIName);
             Data=getappdata(app.UIFigure,'Data');
             
             switch POIName
                 case '\alpha^{SV}_{SH}'
                    setappdata(app.UIFigure,'POISelection',Data.POI(:,1))
                 case '\gamma_{SV}'
                    setappdata(app.UIFigure,'POISelection',Data.POI(:,2))
                 otherwise
                     error('Unrecognized POIName')
             end
             MakePOIHist(app)
             MakePOIQOIScatter(app)
        end

        % Value changed function: QOIDropDown
        function QOISelect(app, event)
            QOIName = app.QOIDropDown.Value;
            setappdata(app.UIFigure,'QOIName',QOIName);
            Data=getappdata(app.UIFigure,'Data');
             switch QOIName
                 case '% Infected'
                     setappdata(app.UIFigure,'QOISelection',Data.QOI(:,1))
                 case 'R0'
                     setappdata(app.UIFigure,'QOISelection',Data.QOI(:,2))
                 otherwise
                     error('Unrecognized QOIName')
             end
             MakeQOIHist(app)
             MakePOIQOIScatter(app)
        end

        % Value changed function: POI2DropDown
        function POISelect2(app, event)
            value = app.POI2DropDown.Value;
                          
        end

        % Value changed function: ModelSelectDropDown
        function ModelSelect(app, event)
            value = app.ModelSelectDropDown.Value;
            AssignModel(app,value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SamplingPanel = uipanel(app.UIFigure);
            app.SamplingPanel.Title = 'Sampling';
            app.POIQOIScatter = uiaxes(app.SamplingPanel);
            title(app.POIQOIScatter, 'POI/ QOI Correlation')
            xlabel(app.POIQOIScatter, 'X')
            ylabel(app.POIQOIScatter, 'Y')
            app.FitModelButton = uibutton(app.SamplingPanel, 'push');
            app.FitModelButton.Text = 'Fit Model';
            app.ModelDropDownLabel = uilabel(app.SamplingPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelSelectDropDown = uidropdown(app.SamplingPanel);
            app.ModelSelectDropDown.Items = {'(Select)', '1st Order Polynomial', '2nd Order Polynomial', '3rd Order Polynomial', 'Logistic'};
            app.ModelSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelect, true);
            app.ModelSelectDropDown.Value = '(Select)';
            app.DummyResidualsLabel = uilabel(app.SamplingPanel);
            app.DummyResidualsLabel.Text = 'Residuals:';
            app.DummyfxLabel = uilabel(app.SamplingPanel);
            app.DummyfxLabel.Text = 'f(x)=';
            app.ResidualsLabel = uilabel(app.SamplingPanel);
            app.ResidualsLabel.Text = ' ';
            app.fxLabel = uilabel(app.SamplingPanel);
            app.fxLabel.Text = ' ';
            app.POIsPanel = uipanel(app.UIFigure);
            app.POIsPanel.Title = 'POIs';
            app.POIHist = uiaxes(app.POIsPanel);
            title(app.POIHist, 'POI Distribution')
            xlabel(app.POIHist, 'X')
            app.QOIsPanel = uipanel(app.UIFigure);
            app.QOIsPanel.Title = 'QOIs';
            app.QOIHist = uiaxes(app.QOIsPanel);
            title(app.QOIHist, 'QOI Distribution')
            xlabel(app.QOIHist, 'X')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.RunModelButton = uibutton(app.UIFigure, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @ReMakeModel, true);
            app.RunModelButton.Text = 'Run Model';
            app.POI1DropDownLabel = uilabel(app.UIFigure);
            app.POI1DropDownLabel.Text = 'POI 1 ';
            app.POI1DropDown = uidropdown(app.UIFigure);
            app.POI1DropDown.Items = {'\alpha^{SV}_{SH}', '\gamma_{SV}', '[''m'' char(179) ''/hr'']'};
            app.POI1DropDown.ValueChangedFcn = createCallbackFcn(app, @POISelect1, true);
            app.POI1DropDown.Value = '\alpha^{SV}_{SH}';
            app.QOIDropDownLabel = uilabel(app.UIFigure);
            app.QOIDropDownLabel.Text = 'QOI';
            app.QOIDropDown = uidropdown(app.UIFigure);
            app.QOIDropDown.Items = {'% Infected', 'R0'};
            app.QOIDropDown.ValueChangedFcn = createCallbackFcn(app, @QOISelect, true);
            app.QOIDropDown.Value = '% Infected';
            app.POI2DropDownLabel = uilabel(app.UIFigure);
            app.POI2DropDownLabel.Text = 'POI 2';
            app.POI2DropDown = uidropdown(app.UIFigure);
            app.POI2DropDown.Items = {'None'};
            app.POI2DropDown.ValueChangedFcn = createCallbackFcn(app, @POISelect2, true);
            app.POI2DropDown.Value = 'None';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisFinal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end