classdef VisFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        POIQOICorrelationPanel      matlab.ui.container.Panel
        POIQOIScatter               matlab.ui.control.UIAxes
        SimulationControlPanel      matlab.ui.container.Panel
        POIDropDownLabel            matlab.ui.control.Label
        POIDropDown                 matlab.ui.control.DropDown
        QOIDropDownLabel            matlab.ui.control.Label
        QOIDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel          matlab.ui.control.Label
        ModelSelectDropDown         matlab.ui.control.DropDown
        DummyResidualsLabel         matlab.ui.control.Label
        ResidualsLabel              matlab.ui.control.Label
        ParameterandQuantityofInterestSelectionLabel  matlab.ui.control.Label
        FitPOIstoQOISLabel          matlab.ui.control.Label
        DummySensitivityIndexLabel  matlab.ui.control.Label
        SensitivityIndexLabel       matlab.ui.control.Label
        EstimateSensitivityLabel    matlab.ui.control.Label
        FindMostSensitivePOIButton  matlab.ui.control.Button
        WelTextArea                 matlab.ui.control.TextArea
        WelcometotheGlobalSensitivityAnalysisInterfaceLabel  matlab.ui.control.Label
        QOIDistributionPanel        matlab.ui.container.Panel
        QOIHist                     matlab.ui.control.UIAxes
        DummyQOIMeanLabel           matlab.ui.control.Label
        QOIMeanLabel                matlab.ui.control.Label
        DummyQOISTDLabel            matlab.ui.control.Label
        QOISTDLabel                 matlab.ui.control.Label
        DummyQOIMaxLabel            matlab.ui.control.Label
        QOIMaxLabel                 matlab.ui.control.Label
        DummyQOIMinLabel            matlab.ui.control.Label
        QOIMinLabel                 matlab.ui.control.Label
        POIDistributionPanel        matlab.ui.container.Panel
        POIHist                     matlab.ui.control.UIAxes
        DummyPOIMeanLabel           matlab.ui.control.Label
        POIMeanLabel                matlab.ui.control.Label
        DummyPOISTDLabel            matlab.ui.control.Label
        POISTDLabel                 matlab.ui.control.Label
        DummyPOIMaxLabel            matlab.ui.control.Label
        POIMaxLabel                 matlab.ui.control.Label
        DummyPOIMinLabel            matlab.ui.control.Label
        POIMinLabel                 matlab.ui.control.Label
    end


    methods (Access = private)
        function MakeModel(app)
            %In reality this will run some large code like GSA
             S=load("GSAData.mat");
%              Data.POI=randn(1000,2);
%              Data.QOI=randn(1000,2);
             setappdata(app.UIFigure,'Data',S.Data);
             
            POINames={'Sylvatic Host Force of Infection' 'Sylvatic Vector Force of Infection' ...
                'Domestic Host Force of Infection' 'Domestic Vector Force of Infection' 'Vector Movement Rate'...
                'Vector Death Rate'};
            QOINames={'% Infected Vectors (Equilibrium)', '% Infected Vectors (t=1)','R0'};
            
            setappdata(app.UIFigure,'POINameList',POINames);
            setappdata(app.UIFigure,'QOINameList',QOINames);
        end
        
        
        function AssignModel(app,Name)
             app.ModelSelectDropDown.Value=Name;
            %Model Class Definition
               %.Name: String of Model Name
               %.Type: 'Linear' v 'NonLinear'
               %.Coeff: vector of coeffecients, passed as zeros initially
               %.EvalFunc: Function that takes x (single or column vector) and 
               %            vector of coeffecients and outputs y with dim ==dim(x)
               %.XFunc: Linear Models only. Function that takes x (single or 
               %        column vector) and outputs X with size [length(x) length(Coeff)]
               %        where Xij is xi evaluated at the jth basis function of the Model
               %.StringFormat: formatspec for sprintf call to display basis function with Coeff.
               %               written so string will be passed through latex interpreter
               %.RTR: Residual sum of squares for the model fit, NaN before assignment in FitModel
            
            switch Name
                case '(Select)'
                    MakePOIQOIScatter(app);
                case '1st Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x);
                    Model.XFunc=@(x)[ones(size(x)) x];
                    Model.StringFormat="$f(x)=%.2f%+.2fx$";
                    Model.RTR=NaN(1);
                case '2nd Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x+Coeff(3).*x.^2);
                    Model.XFunc=@(x)[ones(size(x)) x x.^2];
                    Model.StringFormat="$f(x)=%.2f%+.2fx%+.2fx^2$";
                    Model.RTR=NaN(1);
                case '3rd Order Polynomial'
                    Model.Name=Name;
                    Model.Type='Linear';
                    Model.Coeff=[0 0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x+Coeff(3).*x.^2+Coeff(4).*x.^3);
                    Model.XFunc=@(x)[ones(size(x)) x x.^2 x.^3];
                    Model.StringFormat="$f(x)=%.2f%+.2fx%+.2fx^2%+.2fx^3$";
                    Model.RTR=NaN(1);
                case 'Logistic'
                    Model.Name=Name;
                    Model.Type='NonLinear';
                    Model.Coeff=[0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)./(1+exp(-Coeff(2).*(x-Coeff(3)))));
                    Model.StringFormat="$f(x)=\\frac{%.2f}{1+e^{%.2f(x-%.2f)}}$";
                    Model.RTR=NaN(1);
                case 'Exponential'
                    Model.Name=Name;
                    Model.Type='NonLinear';
                    Model.Coeff=[0 0 0];
                    Model.EvalFunc=@(x,Coeff)(Coeff(1)*ones(size(x))+Coeff(2)*exp(Coeff(3).*x));
                    Model.StringFormat="$f(x)=%.2f+%.2fe^{%.2fx}$";
                    Model.RTR=NaN(1);
                otherwise
                    error('Model Name not recognized')
            end
            if ~strcmpi(Name,'(Select)')
                setappdata(app.UIFigure,'Model',Model)
                FitModel(app);
            end
        end


        function FitModel(app)
            %ModelClass
               %.Name: String of Model Name
               %.Type: 'Linear' v 'NonLinear'
               %.Coeff: vector of coeffecients, passed as zeros initially
               %.EvalFunc: Function that takes x (single or column vector) and 
               %            vector of coeffecients and outputs y with dim ==dim(x)
               %.XFunc: Linear Models only. Function that takes x (single or 
               %        column vector) and outputs X with size [length(x) length(Coeff)]
               %        where Xij is xi evaluated at the jth basis function of the Model
               %.StringFormat: formatspec for sprintf call to display basis function with Coeff.
               %               written so string will be passed through latex interpreter
               %.RTR: Residual sum of squares for the model fit, NaN before assignment in FitModel
            %Load Data
            QOI=getappdata(app.UIFigure,'QOISelection');
            POI=getappdata(app.UIFigure,'POISelection');
            
            %Get basis function
            Model=getappdata(app.UIFigure,'Model');
            
            %Get Coeffecients-Fitting Linear Model
            if strcmpi(Model.Type,'Linear')
                X=Model.XFunc(POI);
                Coeff=(X'*X)\(X'*QOI);
            elseif strcmpi(Model.Type,'NonLinear')
                Coeff0=zeros(1,length(Model.Coeff));
                RTRFunc=@(Coeff)(sum((QOI-Model.EvalFunc(POI,Coeff)).^2));
                Coeff=lsqnonlin(RTRFunc,Coeff0);
            else
                error('Unrecognized basis.Type')
            end
            
            %Get sum residuals squared
            R=QOI-Model.EvalFunc(POI,Coeff);
            RTR=R'*R;
            
            %Pass Results
            Model.RTR=RTR;
            Model.Coeff=Coeff;
            setappdata(app.UIFigure,'Model',Model)
            
            %Plot Results
            DisplayFit(app)
        end
    
        function DisplayFit(app)
            Model=getappdata(app.UIFigure,'Model');
            %Plot model on scatter
                POIs=getappdata(app.UIFigure,'POISelection');
                POIRange=linspace(min(POIs),max(POIs),200);
                hold(app.POIQOIScatter)
                regression=plot(app.POIQOIScatter,POIRange,Model.EvalFunc(POIRange,Model.Coeff),'r');
                hold(app.POIQOIScatter)
                %label
                flegendString=sprintf(Model.StringFormat,Model.Coeff);
                L=legend(app.POIQOIScatter,"Data",flegendString);
                set(L,'Interpreter','LaTex')
            %Edit ResidualLabel
            ResidualLabelString=sprintf("%.3e",Model.RTR);
            app.ResidualsLabel.Text=ResidualLabelString;
            setappdata(app.UIFigure,'regression',regression);
            %Edit ResidualLabel
            %setappdata(app.UIFigure,'Model');
        end


        function MakePOIHist(app)
            %Make Histogram
            POISelection=getappdata(app.UIFigure,'POISelection');
            POIName=getappdata(app.UIFigure,'POIName');
            histogram(app.POIHist,POISelection)
            xlabel(app.POIHist,POIName)
            
            %Adjust Metrics
            app.POIMeanLabel.Text=sprintf('%.2e',mean(POISelection));
            app.POISTDLabel.Text=sprintf('%.2e',std(POISelection));
            app.POIMaxLabel.Text=sprintf('%.2e',max(POISelection));
            app.POIMinLabel.Text=sprintf('%.2e',min(POISelection));
        end
    
        
        function MakeQOIHist(app)
            QOISelection=getappdata(app.UIFigure,'QOISelection');
            QOIName=getappdata(app.UIFigure,'QOIName');
            histogram(app.QOIHist,QOISelection)
            xlabel(app.QOIHist,QOIName)
            
            %Adjust Metrics
            app.QOIMeanLabel.Text=sprintf('%.2e',mean(QOISelection));
            app.QOISTDLabel.Text=sprintf('%.2e',std(QOISelection));
            app.QOIMaxLabel.Text=sprintf('%.2e',max(QOISelection));
            app.QOIMinLabel.Text=sprintf('%.2e',min(QOISelection));
        end
        
        function MakePOIQOIScatter(app)
            QOISelection=getappdata(app.UIFigure,'QOISelection');
            QOIName=getappdata(app.UIFigure,'QOIName');
            POISelection=getappdata(app.UIFigure,'POISelection');
            POIName=getappdata(app.UIFigure,'POIName');
            plot(app.POIQOIScatter,POISelection,QOISelection,"*");
            xlabel(app.POIQOIScatter,POIName,'FontSize',12);
            ylabel(app.POIQOIScatter,QOIName,'FontSize',12);
            legend(app.POIQOIScatter,'Data');
            app.ResidualsLabel.Text=' ';
%             datacursormode on
%             dcm = datacursormode(gcf);
%             set(dcm,'UpdateFcn',@myupdatefcn)
        end
       
        
        function SI=GetSensitivity(app,POI)
                QOI=getappdata(app.UIFigure,'QOISelection');
                %Coeff0=[0 0];
                %EvalFunc=@(x,Coeff)(Coeff(1)+Coeff(2).*x);
                XFunc=@(x)[ones(size(x)) x];
                
                X=XFunc(POI);
                Coeff=(X'*X)\(X'*QOI);
                
                %Estimate Scaled Sensitivity Index
                SI=mean(POI)/mean(QOI)*Coeff(2);
        end
        
        function SetPOI(app,POIName)
             app.POIDropDown.Value=POIName;
             setappdata(app.UIFigure,'POIName',POIName);
             POINameList=getappdata(app.UIFigure,'POINameList');
             Data=getappdata(app.UIFigure,'Data');
             switch POIName
                 case POINameList{1}
                    setappdata(app.UIFigure,'POISelection',Data.POI(:,1));
                 case POINameList{2}
                     setappdata(app.UIFigure,'POISelection',Data.POI(:,2));
                 case POINameList{3}
                    setappdata(app.UIFigure,'POISelection',Data.POI(:,3));
                 case POINameList{4}
                     setappdata(app.UIFigure,'POISelection',Data.POI(:,4));
                 case POINameList{5}
                    setappdata(app.UIFigure,'POISelection',Data.POI(:,5));
                 case POINameList{6}
                     setappdata(app.UIFigure,'POISelection',Data.POI(:,6));
                 otherwise
                     error('Unrecognized POIName')
             end
             MakePOIHist(app)
             MakePOIQOIScatter(app)
             AssignModel(app,app.ModelSelectDropDown.Value)
             SI=GetSensitivity(app,getappdata(app.UIFigure,'POISelection'));
             app.SensitivityIndexLabel.Text=sprintf('%.2f',SI);
        end
        
        function SetQOI(app,QOIName)
             app.QOIDropDown.Value=QOIName;
             setappdata(app.UIFigure,'QOIName',QOIName);
             QOINameList=getappdata(app.UIFigure,'QOINameList');
             Data=getappdata(app.UIFigure,'Data');
             switch QOIName
                 case QOINameList{1}
                     setappdata(app.UIFigure,'QOISelection',Data.QOI(:,1)*100)
                 case QOINameList{2}
                     setappdata(app.UIFigure,'QOISelection',Data.QOI(:,2)*100)
                 case QOINameList{3}
                     setappdata(app.UIFigure,'QOISelection',Data.QOI(:,3))
                 otherwise
                     error('Unrecognized QOIName')
             end
             MakeQOIHist(app)
             MakePOIQOIScatter(app)
             AssignModel(app,app.ModelSelectDropDown.Value)
             SI=GetSensitivity(app,getappdata(app.UIFigure,'POISelection'));
             app.SensitivityIndexLabel.Text=sprintf('%.2f',SI);
        end
    
%         function txt = myupdatefcn(app,trash,event)
%             pos = get(event,'Position');
%             dts = get(event.Target,'Tag');
%             txt = {dts,...
%                    ['X: ',num2str(pos(1))],...
%                  ['Y: ',num2str(pos(2))]};
%         end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MakeModel(app)
            Data=getappdata(app.UIFigure,'Data');


            setappdata(app.UIFigure,'POIName','Sylvatic Host Force of Infection');
            setappdata(app.UIFigure,'POISelection',Data.POI(:,1));
            
            setappdata(app.UIFigure,'QOIName','% Infected Vectors (Equilibrium)');
            setappdata(app.UIFigure,'QOISelection',Data.QOI(:,1));
            MakePOIHist(app);
            MakeQOIHist(app);
            MakePOIQOIScatter(app);
            SI=GetSensitivity(app,getappdata(app.UIFigure,'POISelection'));
            app.SensitivityIndexLabel.Text=sprintf('%.2f',SI);
        end

        % Callback function
        function ReMakeModel(app, event)
        %In reality this will run some large code like GSA
             MakeModel(app);
        end

        % Value changed function: POIDropDown
        function POISelect1(app, event)
             POIName=app.POIDropDown.Value;
             SetPOI(app,POIName);
        end

        % Value changed function: QOIDropDown
        function QOISelect(app, event)
            QOIName = app.QOIDropDown.Value;
            SetQOI(app,QOIName);
        end

        % Value changed function: ModelSelectDropDown
        function ModelSelect(app, event)
            value = app.ModelSelectDropDown.Value;
            MakePOIQOIScatter(app);
            AssignModel(app,value);
        end

        % Callback function
        function SamplingPanelSizeChanged(app, event)

        end

        % Button pushed function: FindMostSensitivePOIButton
        function FindMostSensitivePOIButtonPushed(app, event)
            Data=getappdata(app.UIFigure,'Data');
            POINameList=getappdata(app.UIFigure,'POINameList');
            POIs=Data.POI;
            SI=GetSensitivity(app,POIs(:,1));
            POINumber=1;
            for i=2:size(POIs,2)
                SInew=GetSensitivity(app,POIs(:,i));
                if SInew>SI
                    POINumber=i;
                    SI=SInew;
                end
            end
            
            POIName=POINameList{POINumber};
            SetPOI(app,POIName);
            f=getappdata(app.UIFigure,'regression');
            delete(f);
            AssignModel(app,'1st Order Polynomial')
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.POIQOICorrelationPanel = uipanel(app.UIFigure);
            app.POIQOICorrelationPanel.Title = 'POI/ QOI Correlation';
            app.POIQOIScatter = uiaxes(app.POIQOICorrelationPanel);
            xlabel(app.POIQOIScatter, 'X')
            ylabel(app.POIQOIScatter, 'Y')
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.POIDropDownLabel = uilabel(app.SimulationControlPanel);
            app.POIDropDownLabel.Text = 'POI  ';
            app.POIDropDown = uidropdown(app.SimulationControlPanel);
            app.POIDropDown.Items = {'Sylvatic Host Force of Infection', 'Sylvatic Vector Force of Infection', 'Domestic Host Force of Infection', 'Domestic Vector Force of Infection', 'Vector Movement Rate', 'Vector Death Rate'};
            app.POIDropDown.ValueChangedFcn = createCallbackFcn(app, @POISelect1, true);
            app.POIDropDown.Value = 'Sylvatic Host Force of Infection';
            app.QOIDropDownLabel = uilabel(app.SimulationControlPanel);
            app.QOIDropDownLabel.Text = 'QOI';
            app.QOIDropDown = uidropdown(app.SimulationControlPanel);
            app.QOIDropDown.Items = {'% Infected Vectors (Equilibrium)', '% Infected Vectors (t=1)', 'R0'};
            app.QOIDropDown.ValueChangedFcn = createCallbackFcn(app, @QOISelect, true);
            app.QOIDropDown.Value = '% Infected Vectors (Equilibrium)';
            app.ModelDropDownLabel = uilabel(app.SimulationControlPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelSelectDropDown = uidropdown(app.SimulationControlPanel);
            app.ModelSelectDropDown.Items = {'(Select)', '1st Order Polynomial', '2nd Order Polynomial', '3rd Order Polynomial', 'Logistic', 'Exponential'};
            app.ModelSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelect, true);
            app.ModelSelectDropDown.Value = '(Select)';
            app.DummyResidualsLabel = uilabel(app.SimulationControlPanel);
            app.DummyResidualsLabel.Text = 'Residual:';
            app.ResidualsLabel = uilabel(app.SimulationControlPanel);
            app.ResidualsLabel.Text = ' ';
            app.ParameterandQuantityofInterestSelectionLabel = uilabel(app.SimulationControlPanel);
            app.ParameterandQuantityofInterestSelectionLabel.Text = 'Parameter and Quantity of Interest Selection';
            app.FitPOIstoQOISLabel = uilabel(app.SimulationControlPanel);
            app.FitPOIstoQOISLabel.Text = 'Fit POIs to QOIS';
            app.DummySensitivityIndexLabel = uilabel(app.SimulationControlPanel);
            app.DummySensitivityIndexLabel.Text = 'Sensitivity Index:';
            app.SensitivityIndexLabel = uilabel(app.SimulationControlPanel);
            app.SensitivityIndexLabel.Text = '';
            app.EstimateSensitivityLabel = uilabel(app.SimulationControlPanel);
            app.EstimateSensitivityLabel.Text = 'Estimate Sensitivity';
            app.FindMostSensitivePOIButton = uibutton(app.SimulationControlPanel, 'push');
            app.FindMostSensitivePOIButton.ButtonPushedFcn = createCallbackFcn(app, @FindMostSensitivePOIButtonPushed, true);
            app.FindMostSensitivePOIButton.Text = 'Find Most Sensitive POI';
            app.WelTextArea = uitextarea(app.SimulationControlPanel);
            app.WelTextArea.Editable = 'off';
            app.WelTextArea.Value = {'To begin analyzing, select any desired parameter of interest (POI) and quantity of interest (QOI) to see their data distribution. You can also fit their relationship using a number of statistical models and calculate their residual sum of squares. Finally, you can see the estimated relative sensitivity index for any POI/QOI relationship, or click the orange button to find the most sensitive POI for the selected QOI.'};
            app.WelcometotheGlobalSensitivityAnalysisInterfaceLabel = uilabel(app.SimulationControlPanel);
            app.WelcometotheGlobalSensitivityAnalysisInterfaceLabel.Text = 'Welcome to the Global Sensitivity Analysis Interface!';
            app.QOIDistributionPanel = uipanel(app.UIFigure);
            app.QOIDistributionPanel.Title = 'QOI Distribution';
            app.QOIHist = uiaxes(app.QOIDistributionPanel);
            xlabel(app.QOIHist, 'X')
            app.DummyQOIMeanLabel = uilabel(app.QOIDistributionPanel);
            app.DummyQOIMeanLabel.Text = 'Mean:';
            app.QOIMeanLabel = uilabel(app.QOIDistributionPanel);
            app.QOIMeanLabel.Text = '';
            app.DummyQOISTDLabel = uilabel(app.QOIDistributionPanel);
            app.DummyQOISTDLabel.Text = 'STD:';
            app.QOISTDLabel = uilabel(app.QOIDistributionPanel);
            app.QOISTDLabel.Text = '';
            app.DummyQOIMaxLabel = uilabel(app.QOIDistributionPanel);
            app.DummyQOIMaxLabel.Text = 'Max:';
            app.QOIMaxLabel = uilabel(app.QOIDistributionPanel);
            app.QOIMaxLabel.Text = '';
            app.DummyQOIMinLabel = uilabel(app.QOIDistributionPanel);
            app.DummyQOIMinLabel.Text = 'Min';
            app.QOIMinLabel = uilabel(app.QOIDistributionPanel);
            app.QOIMinLabel.Text = '';
            app.POIDistributionPanel = uipanel(app.UIFigure);
            app.POIDistributionPanel.Title = 'POI Distribution';
            app.POIHist = uiaxes(app.POIDistributionPanel);
            xlabel(app.POIHist, 'X')
            app.DummyPOIMeanLabel = uilabel(app.POIDistributionPanel);
            app.DummyPOIMeanLabel.Text = 'Mean:';
            app.POIMeanLabel = uilabel(app.POIDistributionPanel);
            app.POIMeanLabel.Text = '';
            app.DummyPOISTDLabel = uilabel(app.POIDistributionPanel);
            app.DummyPOISTDLabel.Text = 'STD:';
            app.POISTDLabel = uilabel(app.POIDistributionPanel);
            app.POISTDLabel.Text = '';
            app.DummyPOIMaxLabel = uilabel(app.POIDistributionPanel);
            app.DummyPOIMaxLabel.Text = 'Max:';
            app.POIMaxLabel = uilabel(app.POIDistributionPanel);
            app.POIMaxLabel.Text = '';
            app.DummyPOIMinLabel = uilabel(app.POIDistributionPanel);
            app.DummyPOIMinLabel.Text = 'Min';
            app.POIMinLabel = uilabel(app.POIDistributionPanel);
            app.POIMinLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisFinal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end